classdef manage_measurement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        numberofelementsonscaleTextField  matlab.ui.control.EditField
        measuredweightEditField        matlab.ui.control.EditField
        measuredweightLabel            matlab.ui.control.Label
        weighingstrategyDropDown       matlab.ui.control.DropDown
        weighingstrategyDropDownLabel  matlab.ui.control.Label
        estimateddeviceprecisionEditField  matlab.ui.control.NumericEditField
        estimateddeviceprecisionEditFieldLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        measurementsdoneGauge          matlab.ui.control.LinearGauge
        masselementsonscaleEditField   matlab.ui.control.EditField
        masselementsonscaleEditFieldLabel  matlab.ui.control.Label
        offsetelementCheckBox          matlab.ui.control.CheckBox
        listofmeasurementsListBox      matlab.ui.control.ListBox
        SaveButton                     matlab.ui.control.Button
        totalelementsEditField         matlab.ui.control.NumericEditField
        totalelementsLabel             matlab.ui.control.Label
        removeelementsListBox          matlab.ui.control.ListBox
        removeelementsListBoxLabel     matlab.ui.control.Label
        addelementsListBox             matlab.ui.control.ListBox
        addelementsListBoxLabel        matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        n_el % number of elements
        b_offs % boolean for offset status
        est_prec % estimated precision (reading precision) of measuring device
        binMatrix % array of binary rows indicating the relevant mass elements per measurement
        currentonscale % holds current elements-on-scale status
        lastonscale % holds previous elements-on-scale status
        currMsrmt % index of current measurement
        measurementsidx % index list of performed measurements
        linRegRes % list of linear regression results after n measurements
        Clr_totVal % colors for plotting
        Clr_elVal % colors for plotting
        b_ternary % boolean for sequence basis selection: true for ternary, false for binary
    end
    
    properties (Access = public)
        measValues % vector of measured values
        timeStamp % time vector of recorded data
    end
    
    methods (Access = private)
        
        function performMeasurement(app)
            app.currMsrmt = app.listofmeasurementsListBox.Value;
            
            if isnan(app.measValues(app.currMsrmt))
                app.measuredweightEditField.Value = '';
            else
                app.measuredweightEditField.Value = num2str(app.measValues(app.currMsrmt), '%11.4g');
            end
            
            app.currentonscale = app.binMatrix(app.currMsrmt,:);
            
            if app.b_ternary
                list_toAdd = find(app.currentonscale == 1) - app.b_offs';
                list_toRemove = find(app.currentonscale == -1) - app.b_offs';
                
                app.masselementsonscaleEditField.Value = ...
                    sprintf('%s | %s', ...
                    num2str(-(find(app.lastonscale == -1) - app.b_offs')), ...
                    num2str(-(find(app.lastonscale == 1) - app.b_offs')));
                app.numberofelementsonscaleTextField.Value = sprintf('%1i | %1i',sum(app.lastonscale<0), sum(app.lastonscale>0));
                
            else
                app.masselementsonscaleEditField.Value = num2str(find(app.lastonscale) - app.b_offs);
                app.numberofelementsonscaleTextField.Value = num2str(sum(app.lastonscale));
                
                list_toAdd = find((app.currentonscale - app.lastonscale) == 1) - app.b_offs';
                list_toRemove = find((app.currentonscale - app.lastonscale) == -1) - app.b_offs';
            end
            
            if isempty(list_toAdd)
                app.addelementsListBox.Items = {};
            else
                app.addelementsListBox.Items = mat2cell(num2str(list_toAdd), ones(1,size(list_toAdd,1)));
            end
            
            if isempty(list_toRemove)
                app.removeelementsListBox.Items = {};
            else
                app.removeelementsListBox.Items = mat2cell(num2str(list_toRemove), ones(1,size(list_toRemove,1)));
            end
            
        end
        
        function plotCurrentResults(app)
%             perfMeas = app.measurementsdone;
            perfMeas = ~isnan(app.measValues);
            h_plot1 = app.UIAxes1.Children(3);
            h_plot3 = app.UIAxes3.Children(4);
            h_plot31 = app.UIAxes3.Children(3);
            h_plot4 = app.UIAxes3.Children([1 2]);
            h_plot5 = app.UIAxes2.Children;
            
            lm = sum(~isnan(app.measValues));
           
            this_bin_matrix = app.binMatrix(perfMeas,:);
            this_meas_data = app.measValues(perfMeas);
            this_linRegRes = this_bin_matrix\this_meas_data;
            app.linRegRes(:,lm) = this_linRegRes;
            
            result_Matrix = this_bin_matrix*this_linRegRes;
            Delta_totMass = (this_meas_data - result_Matrix);
            h_plot1.XData = app.listofmeasurementsListBox.ItemsData(perfMeas);
            h_plot1.YData = Delta_totMass;
            h_plot1.CData = [abs(Delta_totMass)>app.est_prec/2 zeros(numel(Delta_totMass),2)];
            h_plot1.Parent.Title.String = ['|' sprintf(' %2.3g |', this_linRegRes)];
            
            h_plot3.XData = this_meas_data;
            h_plot3.YData = Delta_totMass;
            h_plot3.CData = app.Clr_totVal(perfMeas,:);
            h_plot31.XData = this_meas_data(end);
            h_plot31.YData = Delta_totMass(end);
            h_plot31.Color = app.Clr_totVal(lm,:);
            
            if ~isequal(h_plot4(1).XData, h_plot3.Parent.XLim)
                h_plot4(1).XData = h_plot3.Parent.XLim;
                h_plot4(2).XData = h_plot3.Parent.XLim;
            end
            
            for jj = 1:size(app.binMatrix,2)
                if lm == 1
                    h_plot5(jj).Color = app.Clr_elVal(jj,:);
                end
                h_plot5(jj).XData(end+1) = lm;
                h_plot5(jj).YData(end+1) = app.linRegRes(jj,lm) - app.linRegRes(jj,1);
            end
            
            drawnow;
            
        end
        
        function bM = getBinMatrix(app)
            k_strat = app.weighingstrategyDropDown.Value;
            
            if k_strat < numel(app.weighingstrategyDropDown.ItemsData)
                bM = createBinMtrx_oneDiff(app.n_el); %%% external function
                
                if k_strat < numel(app.weighingstrategyDropDown.ItemsData)-2
                    k = k_strat;
                    bM = bM(sum(bM,2)==k|sum(bM,2)==0,:);
                    bM = sort_BinMtrx4Experiment(bM,0); %%% external function
                end
                
            else
                bM = createTernaryMtrx(app.n_el); %%% external function
            
            end
            
            if app.b_offs == 1
                bM = [ones(size(bM,1),1) bM];
            end

        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.totalelementsEditField.Editable = true;
            app.totalelementsEditField.Value = 0;
            
            app.estimateddeviceprecisionEditField.Editable = true;
            app.estimateddeviceprecisionEditField.Value = 0.1;
            
            app.measuredweightEditField.Editable = false;
            app.measuredweightEditField.Value = '';
            
            app.measurementsdoneGauge.Enable = false;
            app.measurementsdoneGauge.Limits = [0 100];
            
            app.StartButton.Enable = true;
            
            app.SaveButton.Enable = false;
            
            app.offsetelementCheckBox.Enable = true;
            app.offsetelementCheckBox.Value = false;
            
            app.listofmeasurementsListBox.Items = {};
            
            app.masselementsonscaleEditField.Value = '';
            
            app.numberofelementsonscaleTextField.Value = '';
            
            app.weighingstrategyDropDown.Enable = true;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.totalelementsEditField.Editable = false;
            app.estimateddeviceprecisionEditField.Editable = false;
            app.measuredweightEditField.Editable = true;
            app.measurementsdoneGauge.Enable = true;
            app.measurementsdoneGauge.Value = 0;
            app.StartButton.Enable = false;
            app.SaveButton.Enable = true;
            app.offsetelementCheckBox.Enable = false;
            app.weighingstrategyDropDown.Enable = false;
            
            app.n_el = app.totalelementsEditField.Value;
            app.b_offs = app.offsetelementCheckBox.Value;
            app.est_prec = app.estimateddeviceprecisionEditField.Value;
        
            app.binMatrix = app.getBinMatrix();
            app.measValues = nan(size(app.binMatrix,1),1);
            app.timeStamp = nan(size(app.binMatrix,1),1);
            app.linRegRes = nan(size(app.binMatrix,2), numel(app.measValues));
            
            app.measurementsdoneGauge.Limits = [0 size(app.binMatrix,1)];
%             app.listofmeasurementsListBox.Items = mat2cell(num2str(app.binMatrix), ones(1, size(app.binMatrix,1)));
            app.listofmeasurementsListBox.Items = ...
                    cellfun(...
                    @(n, s) sprintf('%1i: %s', n,s), ...
                    num2cell((1:size(app.binMatrix,1))'), ...
                    mat2cell(num2str(app.binMatrix), ones(1, size(app.binMatrix,1))), ...
                    'UniformOutput', false);
            app.listofmeasurementsListBox.ItemsData = 1:size(app.binMatrix,1);
            app.listofmeasurementsListBox.Value = app.listofmeasurementsListBox.ItemsData(1);
            app.lastonscale = zeros(1, size(app.binMatrix,2));
            
            app.Clr_totVal = .8*hsv(numel(app.measValues));
            app.Clr_elVal = .8*hsv(size(app.binMatrix,2)+1);
            
            app.performMeasurement;
            
            %%% plot preparation
            scatter(app.UIAxes1, nan, 1, 16, 'filled'); 
            hold(app.UIAxes1, 'on');
            plot(app.UIAxes1, [0 size(app.binMatrix,1)+1],[1 1]*app.est_prec/2, 'r:', [0 size(app.binMatrix,1)+1],[1 1]*(-app.est_prec/2), 'r:');
            hold(app.UIAxes1, 'off');
            title(app.UIAxes1, sprintf('%g ', nan(1,size(app.binMatrix,2))));
            xlabel(app.UIAxes1, '# measurement');
            ylabel(app.UIAxes1, 'residuals');
            set(app.UIAxes1, 'XLim', [0 size(app.binMatrix,1)+1]);
            box(app.UIAxes1, 'on');
            
            plot(app.UIAxes2, nan, ones(size(app.binMatrix,2),1), '-');
            xlabel(app.UIAxes2, '# measurement');
            title(app.UIAxes2, 'element value difference to initial value');
            ylabel(app.UIAxes2, 'm - m_0')
            set(app.UIAxes2, 'XLim', [0 size(app.binMatrix,1)+1]);
            box(app.UIAxes2, 'on');
            
            scatter(app.UIAxes3, nan, 1, 12, 'filled', 'MarkerFaceAlpha', .5); 
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, nan,1,'.', 'MarkerSize', 24);
            plot(app.UIAxes3, [nan nan],[1 1]*app.est_prec/2, 'r:', [nan nan],[1 1]*(-app.est_prec/2), 'r:');
            hold(app.UIAxes3, 'off');
            xlabel(app.UIAxes3, 'measurement value');
            ylabel(app.UIAxes3, 'residuals');
            box(app.UIAxes3, 'on');
            
        end

        % Value changed function: measuredweightEditField
        function EnterButtonPushed(app, event)
            app.measValues(app.currMsrmt) = str2double(app.measuredweightEditField.Value);
            app.timeStamp(app.currMsrmt) = now;
            app.lastonscale = app.currentonscale;
            if sum(isnan(app.measValues))==0
                uialert(app.UIFigure,'All measurements were performed. Change data for currently selected measurement or click Save button for data export.','Measurements done', 'Icon','success');
            else
                if app.listofmeasurementsListBox.Value == numel(app.listofmeasurementsListBox.ItemsData)||...
                        ~isnan(app.measValues(app.listofmeasurementsListBox.Value + 1))
                    app.listofmeasurementsListBox.Value = find(isnan(app.measValues),1);
                else
                    app.listofmeasurementsListBox.Value = app.listofmeasurementsListBox.Value + 1;
                end
            end
            
            app.measurementsdoneGauge.Value = sum(~isnan(app.measValues));
            app.listofmeasurementsListBox.scroll(app.listofmeasurementsListBox.Value);
            
            app.measurementsidx = [app.measurementsidx app.measurementsdoneGauge.Value];
            
            app.performMeasurement;
            app.plotCurrentResults;
        end

        % Value changed function: listofmeasurementsListBox
        function listofmeasurementsListBoxValueChanged(app, event)
            %             value = app.listofmeasurementsListBox.Value;
            app.performMeasurement;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile({'*.xlsx'; '*.csv'}, 'Save file');
            writematrix([app.timeStamp, app.binMatrix, app.measValues], fullfile(path, file));
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clf(app.UIAxes1);
            clf(app.UIAxes2);
            clf(app.UIAxes3);
            app.startupFcn;
        end

        % Value changed function: totalelementsEditField
        function totalelementsEditFieldValueChanged(app, event)
            n = app.totalelementsEditField.Value;
            nCHk_ItmData = num2cell(1:n+1);
            nCHk_List = cell(1,n+1);
            nCHk_List(1:end-2) = cellfun(@(k_in) sprintf('N = (%1iCH%1i)+1 = %1i', n, k_in, nchoosek(n, k_in)+1), nCHk_ItmData(1:end-2), 'UniformOutput',false);
            nCHk_List{end-1} = sprintf('N = 2^%1i = %1i', n, 2^n);
            nCHk_List{end} = sprintf('N = 3^%1i = %1i', n, 3^n);
            app.weighingstrategyDropDown.Items = nCHk_List;
            app.weighingstrategyDropDown.ItemsData = nCHk_ItmData;
        end

        % Value changing function: measuredweightEditField
        function measuredweightEditFieldValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: weighingstrategyDropDown
        function weighingstrategyDropDownValueChanged(app, event)
            value = app.weighingstrategyDropDown.Value;
            if value == app.weighingstrategyDropDown.ItemsData{end}
                app.b_ternary = true;
                app.removeelementsListBoxLabel.Text = 'negative element(s)';
                app.addelementsListBoxLabel.Text = 'positive element(s)';
            else
                app.b_ternary = false;
                app.removeelementsListBoxLabel.Text = 'remove element(s)';
                app.addelementsListBoxLabel.Text = 'add element(s)';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, 'Residuals vs measurements')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Difference to first result')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Residuals vs total mass')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'start campaign of measurements'};
            app.StartButton.Text = 'Start';
            app.addelementsListBoxLabel = uilabel(app.GridLayout);
            app.addelementsListBoxLabel.Text = 'add element(s)';
            app.addelementsListBox = uilistbox(app.GridLayout);
            app.addelementsListBox.Items = {};
            app.addelementsListBox.Value = {};
            app.removeelementsListBoxLabel = uilabel(app.GridLayout);
            app.removeelementsListBoxLabel.Text = 'remove element(s)';
            app.removeelementsListBox = uilistbox(app.GridLayout);
            app.removeelementsListBox.Items = {};
            app.removeelementsListBox.Value = {};
            app.totalelementsLabel = uilabel(app.GridLayout);
            app.totalelementsLabel.Text = 'total elements';
            app.totalelementsEditField = uieditfield(app.GridLayout, 'numeric');
            app.totalelementsEditField.ValueChangedFcn = createCallbackFcn(app, @totalelementsEditFieldValueChanged, true);
            app.totalelementsEditField.Tooltip = {'number of total elements w/o offset element'};
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'save results to file'};
            app.SaveButton.Text = 'Save';
            app.listofmeasurementsListBox = uilistbox(app.GridLayout);
            app.listofmeasurementsListBox.Items = {};
            app.listofmeasurementsListBox.ValueChangedFcn = createCallbackFcn(app, @listofmeasurementsListBoxValueChanged, true);
            app.listofmeasurementsListBox.Tooltip = {'list of measurements, click to change current measurement'};
            app.listofmeasurementsListBox.Value = {};
            app.offsetelementCheckBox = uicheckbox(app.GridLayout);
            app.offsetelementCheckBox.Tooltip = {'Consider additional offset element'};
            app.offsetelementCheckBox.Text = 'offset element';
            app.masselementsonscaleEditFieldLabel = uilabel(app.GridLayout);
            app.masselementsonscaleEditFieldLabel.Text = 'mass elements on scale';
            app.masselementsonscaleEditField = uieditfield(app.GridLayout, 'text');
            app.masselementsonscaleEditField.Editable = 'off';
            app.masselementsonscaleEditField.Tooltip = {'list of elements on scale'};
            app.measurementsdoneGauge = uigauge(app.GridLayout, 'linear');
            app.measurementsdoneGauge.Enable = 'off';
            app.measurementsdoneGauge.Tooltip = {'progress of all measurements'};
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Resets App'};
            app.ResetButton.Text = '↻';
            app.estimateddeviceprecisionEditFieldLabel = uilabel(app.GridLayout);
            app.estimateddeviceprecisionEditFieldLabel.Text = 'estimated device precision';
            app.estimateddeviceprecisionEditField = uieditfield(app.GridLayout, 'numeric');
            app.estimateddeviceprecisionEditField.Limits = [0 Inf];
            app.weighingstrategyDropDownLabel = uilabel(app.GridLayout);
            app.weighingstrategyDropDownLabel.Text = {'weighing'; ' strategy'};
            app.weighingstrategyDropDown = uidropdown(app.GridLayout);
            app.weighingstrategyDropDown.Items = {'(nCH1)+1', '(nCH2)+1', '2^n', '3^n'};
            app.weighingstrategyDropDown.ItemsData = {'0', '1', '2', '3'};
            app.weighingstrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @weighingstrategyDropDownValueChanged, true);
            app.weighingstrategyDropDown.Value = '0';
            app.measuredweightLabel = uilabel(app.GridLayout);
            app.measuredweightLabel.Text = 'measured weight';
            app.measuredweightEditField = uieditfield(app.GridLayout, 'text');
            app.measuredweightEditField.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.measuredweightEditField.ValueChangingFcn = createCallbackFcn(app, @measuredweightEditFieldValueChanging, true);
            app.measuredweightEditField.Value = '0';
            app.numberofelementsonscaleTextField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manage_measurement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end